classdef game1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        PlayPanel       matlab.ui.container.Panel
        WordsLeftLabel  matlab.ui.control.Label
        SubmitButton    matlab.ui.control.Button
        EditField5      matlab.ui.control.EditField
        EditField4      matlab.ui.control.EditField
        EditField3      matlab.ui.control.EditField
        EditField2      matlab.ui.control.EditField
        EditField       matlab.ui.control.EditField
        Button5         matlab.ui.control.Button
        Button4         matlab.ui.control.Button
        Button3         matlab.ui.control.Button
        Button2         matlab.ui.control.Button
        Button          matlab.ui.control.Button
        GameModePanel   matlab.ui.container.Panel
        ReadyButton     matlab.ui.control.Button
        GameModeButton  matlab.ui.control.Button
        GameModeLabel   matlab.ui.control.Label
        WelcomePanel    matlab.ui.container.Panel
        StartButton     matlab.ui.control.Button
        WelcomeLabel    matlab.ui.control.Label
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DraggingTile  matlab.ui.control.Button
        WordList string
        Dictionary;
        CorrectWord;
   
    end
    
    methods (Access = private)

       function showPanel(app, panel)
            % Hide all panels
            app.WelcomePanel.Visible = 'off';
            app.GameModePanel.Visible = 'off';
            app.PlayPanel.Visible = 'off';

            % Show the selected panel
            panel.Visible = 'on';
        end

        %Dictionary and Word Validation Code

        function loadDictionary(app)
            persistent dictionary;
            if isempty(dictionary)
                fid = fopen('words.txt');
                dictionary = textscan (fid, '%s');
                dictionary = dictionary {1};
                fclose(fid);
            end
            app.Dictionary = dictionary;
        end

         function checkWord(app)
            % Collect the text from each word slot
            wordSlots = [app.EditField, app.EditField2, app.EditField3, app.EditField4, app.EditField5];
            formedWord = '';

            % Loop through each slot and concatenate the text
            for i = 1:length(wordSlots)
                formedWord = [formedWord, wordSlots(i).Value];  % Concatenate the letter in the slot
            end

            % Check if the formed word is valid (you could add your own dictionary here)

            % Display a message based on whether the word is valid or not
            if strcmpi(formedWord, app.CorrectWord)
                uialert(app.UIFigure, 'Word is valid!', 'Success');
            else
                uialert(app.UIFigure, 'Word is invalid. Try again!', 'Error');
            end
        end

        % Function to check if the formed word is valid
        function [dictionary, isValid] = isValidWord(app)
    persistent dict;
    
    if isempty(dict)
        % Load the dictionary only once
        fid = fopen('words.txt');
        dict = textscan(fid, '%s');
        dict = dict{1};  % Extract the cell array from the textscan result
        fclose(fid);
    end
    
    dictionary = dict;  % Return the dictionary
    
    % You can check validity here if needed
    isValid = @(word) any(strcmpi(word, dictionary));  % Check if the word is in the list
end


        %Random Letters Code

        function generateRandomLetters(app)
    disp('Generating random letters');

    % Get the dictionary and validity check function
    [dictionary, isValid] = app.isValidWord();  % Now we get both dictionary and validity function

    % Get a random word from the dictionary that has length 5
    word = dictionary{randi(length(dictionary))};  % Randomly select a word

    % Ensure the selected word is 5 letters long
    while length(word) ~= 5
        word = dictionary{randi(length(dictionary))};
    end
    
    % Generate random letters for the word (if needed)
    letters = char(randi([65, 90], 1, 5));  % Generate random letters (A-Z)
    
    % Set the text of the buttons to the generated letters
    app.Button.Text = letters(1);
    app.Button2.Text = letters(2);
    app.Button3.Text = letters(3);
    app.Button4.Text = letters(4);
    app.Button5.Text = letters(5);

    % Store the correct word
    app.CorrectWord = word;


    % Ensure the UI updates immediately
    drawnow;
end


        % Drag and Drop Code
        function startTileDrag(app, src, ~)
            if isa(src, 'matlab.ui.control.Button')
                app.DraggingTile = src;
                dsp(['Dragging', app.DraggingTile.Text]);
            else
                disp('Invalid drag source');
            end
            app.DraggingTile = tile;
        end

        function dragTile(app, event)
          if ~isempty(app.DraggingTile)
        % Get current mouse position relative to the figure
        currentPoint = app.UIFigure.CurrentPoint;
        
        % Keep the tile size the same
        tileWidth = app.DraggingTile.Position(3);
        tileHeight = app.DraggingTile.Position(4);
        
        % Center the tile on the mouse cursor
        newX = currentPoint(1) - (tileWidth/2);
        newY = currentPoint(2) - (tileHeight/2);
        
        % Make sure the tile stays within the figure bounds
        figWidth = app.UIFigure.Position(3);
        figHeight = app.UIFigure.Position(4);
        
        newX = max(0, min(newX, figWidth - tileWidth));
        newY = max(0, min(newY, figHeight - tileHeight));
        
        % Update the tile position
        app.DraggingTile.Position = [newX, newY, tileWidth, tileHeight];
    end
        end

        function dropTile(app, event)
   dropPos = app.UIFigure.CurrentPoint;

    % Check if dropPos is valid (not NaN)
    if any(isnan(dropPos)) || any(isinf(dropPos))
        disp('Invalid drop position');
        return;  % Skip further execution if the position is invalid
    end

    % Now calculate the position for the DraggingTile button
    app.DraggingTile.Position(1) = dropPos(1) - app.DraggingTile.Position(3)/2;
    app.DraggingTile.Position(2) = dropPos(2) - app.DraggingTile.Position(4)/2;
    
     disp(['Dropped at: ', num2str(dropPos)]);  


    % Update the position
    drawnow;
end

        function overlap = isOverlapping(app, tile, WordSlot)
            tilePos = tile.Position;
            slotPos = WordSlot.Position;
            overlap = (tilePos(1) < slotPos(1) + slotPos(3)) && ...
                      (tilePos(1) + tilePos(3) > slotPos(1)) && ...
                      (tilePos(2) < slotPos(2) + slotPos(4)) && ...
                      (tilePos(2) + tilePos(4) > slotPos(2));
        end
    function showGameModeScreen(app)
        % Hide the WelcomePanel and show the GameModePanel
        app.WelcomePanel.Visible = 'off';  % Hide WelcomePanel
        app.GameModePanel.Visible = 'on';  % Show GameModePanel
    end

    function showFiveNormalScreen(app)
        % Hide the GameModePanel and show the PlayPanel
        app.GameModePanel.Visible = 'off';  % Hide GameModePanel
        app.PlayPanel.Visible = 'on';       % Show PlayPanel (your game screen)
    end

        end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
 app.showPanel(app.GameModePanel);
        end

        % Button pushed function: GameModeButton
        function GameModeButtonPushed(app, event)
            app.showPanel(app.PlayPanel);
        end

        % Button pushed function: ReadyButton
        function ReadyButtonPushed(app, event)
            app.showFiveNormalScreen();
            app.generateRandomLetters();

        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            app.checkWord();
        end

        % Button pushed function: Button, Button2, Button3, Button4, 
        % ...and 1 other component
        function LetterTilesClicked(app, event)
           app.DraggingTile = event.Source;
    
    % Set up event handlers
    set(app.UIFigure, 'WindowButtonMotionFcn', @(src,event)app.dragTile(event));
    set(app.UIFigure, 'WindowButtonUpFcn', @(src,event)app.dropTile(event));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcomePanel = uipanel(app.UIFigure);
            app.WelcomePanel.Title = 'Welcome Panel';
            app.UIAxes = uiaxes(app.WelcomePanel);
            title(app.UIAxes, 'WelcomeAxes')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.WelcomeLabel = uilabel(app.WelcomePanel);
            app.WelcomeLabel.Text = 'WelcomeLabel';
            app.StartButton = uibutton(app.WelcomePanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'StartButton';
            app.GameModePanel = uipanel(app.UIFigure);
            app.GameModePanel.Title = 'GameModePanel';
            app.GameModeLabel = uilabel(app.GameModePanel);
            app.GameModeLabel.Text = 'GameModeLabel';
            app.GameModeButton = uibutton(app.GameModePanel, 'push');
            app.GameModeButton.ButtonPushedFcn = createCallbackFcn(app, @GameModeButtonPushed, true);
            app.GameModeButton.Text = 'GameModeButton';
            app.ReadyButton = uibutton(app.GameModePanel, 'push');
            app.ReadyButton.ButtonPushedFcn = createCallbackFcn(app, @ReadyButtonPushed, true);
            app.ReadyButton.Text = 'ReadyButton';
            app.PlayPanel = uipanel(app.UIFigure);
            app.PlayPanel.Title = 'PlayPanel';
            app.Button = uibutton(app.PlayPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button.Tag = 'LetterTiles';
            app.Button2 = uibutton(app.PlayPanel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button2.Tag = 'LetterTiles';
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.PlayPanel, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button3.Tag = 'LetterTiles';
            app.Button3.Text = 'Button3';
            app.Button4 = uibutton(app.PlayPanel, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button4.Tag = 'LetterTiles';
            app.Button4.Text = 'Button4';
            app.Button5 = uibutton(app.PlayPanel, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @LetterTilesClicked, true);
            app.Button5.Tag = 'LetterTiles';
            app.Button5.Text = 'Button5';
            app.EditField = uieditfield(app.PlayPanel, 'text');
            app.EditField.Tag = 'WordSlots';
            app.EditField2 = uieditfield(app.PlayPanel, 'text');
            app.EditField2.Tag = 'WordSlots';
            app.EditField3 = uieditfield(app.PlayPanel, 'text');
            app.EditField3.Tag = 'WordSlots';
            app.EditField4 = uieditfield(app.PlayPanel, 'text');
            app.EditField4.Tag = 'WordSlots';
            app.EditField5 = uieditfield(app.PlayPanel, 'text');
            app.EditField5.Tag = 'WordSlots';
            app.SubmitButton = uibutton(app.PlayPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'SubmitButton';
            app.WordsLeftLabel = uilabel(app.PlayPanel);
            app.WordsLeftLabel.Text = 'WordsLeftLabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end